classdef ProjectileMotionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        AngleSlider    matlab.ui.control.Slider
        AngleLabel     matlab.ui.control.Label
        AnimateButton  matlab.ui.control.Button
        UIAxes         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AnimateButton
        function AnimateButtonPushed(app, event)
            % Get the launch angle from the slider
            theta = app.AngleSlider.Value; % launch angle (degrees)
            v0 = 20; % initial speed (m/s)
            g = 9.81; % gravity (m/s^2)
            dt = 0.02; % time step for animation

            % Compute trajectory
            t = linspace(0, 2*v0*sind(theta)/g, 200);
            x = v0*cosd(theta).*t;
            y = v0*sind(theta).*t - 0.5*g*t.^2;

            % Clear previous plot
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            grid(app.UIAxes, 'on');
            xlabel(app.UIAxes, 'Distance (m)');
            ylabel(app.UIAxes, 'Height (m)');
            title(app.UIAxes, sprintf('Projectile Motion Animation (θ = %.1f°)', theta));
            axis(app.UIAxes, [0 max(x)*1.1 0 max(y)*1.2]);
            axis(app.UIAxes, 'manual');

            % Initialize ball and path
            ball = plot(app.UIAxes, x(1), y(1), 'ro', 'MarkerFaceColor', 'r', 'MarkerSize', 10);
            trail = plot(app.UIAxes, nan, nan, 'b', 'LineWidth', 1.5); % empty path

            % Animate motion
            for i = 1:length(t)
                % Update ball position
                set(ball, 'XData', x(i), 'YData', y(i));

                % Update trail up to current point
                set(trail, 'XData', x(1:i), 'YData', y(1:i));

                % Refresh frame
                drawnow;
                pause(dt);
            end

            % End of flight message
            text(app.UIAxes, max(x)/2, max(y)/2, 'Projectile Landed!', 'FontSize', 12, ...
                 'FontWeight', 'bold', 'HorizontalAlignment', 'center', 'Color', [0.2 0.5 0.2]);
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Projectile Motion')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AnimateButton = uibutton(app.UIFigure, 'push');
            app.AnimateButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateButtonPushed, true);
            app.AnimateButton.Text = 'Animate';
            app.AngleLabel = uilabel(app.UIFigure);
            app.AngleLabel.Text = 'Angle';
            app.AngleSlider = uislider(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectileMotionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end